classdef Project1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BeamlengthminLabel            matlab.ui.control.Label
        Beam_length                   matlab.ui.control.NumericEditField
        AddbeamButton                 matlab.ui.control.Button
        Support1                      matlab.ui.control.Button
        SupportsLabel                 matlab.ui.control.Label
        Support2                      matlab.ui.control.Button
        Support3                      matlab.ui.control.Button
        Support_left                  matlab.ui.control.Button
        Support_right                 matlab.ui.control.Button
        PositionmLabel_4              matlab.ui.control.Label
        Position_of_Support           matlab.ui.control.NumericEditField
        AddSupport                    matlab.ui.control.Button
        AppliedForcesLabel            matlab.ui.control.Label
        AddButton                     matlab.ui.control.Button
        PositionminLabel              matlab.ui.control.Label
        ValuekNkipsLabel              matlab.ui.control.Label
        PositionofstartpointminLabel  matlab.ui.control.Label
        Start_valuekNkipsLabel        matlab.ui.control.Label
        End_valuekNkipsLabel          matlab.ui.control.Label
        PositionminLabel_2            matlab.ui.control.Label
        ValuekNmkipsinLabel           matlab.ui.control.Label
        PLoad_P                       matlab.ui.control.NumericEditField
        PLoad_V                       matlab.ui.control.NumericEditField
        DLoad_P_S                     matlab.ui.control.NumericEditField
        DLoad_V_S                     matlab.ui.control.NumericEditField
        DLoad_V_E                     matlab.ui.control.NumericEditField
        Moment_P                      matlab.ui.control.NumericEditField
        Moment_V                      matlab.ui.control.NumericEditField
        SolveButton                   matlab.ui.control.Button
        PointloadLabel                matlab.ui.control.Label
        DistributedloadLabel          matlab.ui.control.Label
        MomentLabel                   matlab.ui.control.Label
        DLoad_P_E                     matlab.ui.control.NumericEditField
        PositionofendpointminLabel    matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        EIMPam4Mpsiin2Label           matlab.ui.control.Label
        EI                            matlab.ui.control.NumericEditField
        UnitsLabel                    matlab.ui.control.Label
        UnitsDropDown                 matlab.ui.control.DropDown
        DownwardispositiveLabel       matlab.ui.control.Label
        counterclockwiseispositiveLabel  matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        I                             matlab.ui.control.NumericEditField
        Im4in4Label                   matlab.ui.control.Label
        E                             matlab.ui.control.NumericEditField
        EMpaMpsiLabel                 matlab.ui.control.Label
        Maximumdeflection             matlab.ui.control.Label
        MaximumShearForce             matlab.ui.control.Label
        MaximumBendingmoment          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        Max_Deflection                matlab.ui.control.Label
        Max_SForce                    matlab.ui.control.Label
        Max_Bmoment                   matlab.ui.control.Label
        DeflectionSliderLabel         matlab.ui.control.Label
        DeflectionSlider              matlab.ui.control.Slider
        ShearForceSliderLabel         matlab.ui.control.Label
        ShearForceSlider              matlab.ui.control.Slider
        BendingMomentSliderLabel      matlab.ui.control.Label
        BendingMomentSlider           matlab.ui.control.Slider
        SForce                        matlab.ui.control.Label
        Deflection                    matlab.ui.control.Label
        Bmoment                       matlab.ui.control.Label
    end


    properties (Access = public)
        Supporttype % type of a support
        Supports % number and type of each support in an array
        Sum_PLoad % Sum of all point loads
        Sum_DLoad % Sum of all distributed loads
        Sum_Moment %Sum of all Moments
        i = 1 % counter
        j = 1 %counter
        force_unit % unit of force
        length_unit % unit of length
        v %deflection
        v_1 %shear force
        M_2 %bending moment
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %status of Buttons and fields when the app started
            app.Beam_length.Enable = 'off'
            app.EI.Enable = 'off'
            app.AddbeamButton.Enable = 'off'
            app.AddSupport.Enable = 'off'
            app.Support1.Enable = 'off'
            app.Support2.Enable = 'off'
            app.Support3.Enable = 'off'
            app.Position_of_Support.Enable = 'off'
            app.Support_left.Enable = 'off'
            app.Support_right.Enable = 'off'
            app.PLoad_P.Enable = 'off'
            app.PLoad_V.Enable = 'off'
            app.DLoad_P_S.Enable = 'off'
            app.DLoad_P_E.Enable = 'off'
            app.DLoad_V_S.Enable = 'off'
            app.DLoad_V_E.Enable = 'off'
            app.Moment_P.Enable = 'off'
            app.Moment_V.Enable = 'off'
            app.AddButton.Enable = 'off'
            app.SolveButton.Enable = 'off'
            app.ClearButton.Enable = 'off'
            app.E.Enable = 'off'
            app.I.Enable = 'off'
            title(app.UIAxes, 'Deflection','Fontsize', 16)
            title(app.UIAxes_2, 'Shear Force','Fontsize', 16)
            title(app.UIAxes_3, 'Bending Moment','Fontsize', 16)
            app.UIAxes.FontSize = 13;
            app.UIAxes_2.FontSize = 13;
            app.UIAxes_3.FontSize = 13;
        end

        % Button pushed function: AddbeamButton
        function AddbeamButtonPushed(app, event)
           %showing the beam
            value = app.Beam_length.Value
            
            fig = app.UIFigure;
            ax = uiaxes(fig,'position',[78 60 500 100]);
            imshow('Beam.png','parent',ax);
            
            value_s = num2str(value);
            fig = app.UIFigure;
            ax = uiaxes(fig,'position',[78 0 600 100]);
            imshow('Axes.png','parent',ax);
            
            uf = app.UIFigure;
            tlabel = uilabel(uf,'Text', '0','Position',[105 15 500 30]);
            tlabel = uilabel(uf,'Text', value_s,'Position',[530 15 500 30]);
            tlabel = uilabel(uf,'Text', ['(',app.length_unit,')'], 'position', [563 35 500 30]);
            
            app.Support1.Enable = 'on'
            app.Support2.Enable = 'on'
            app.Support3.Enable = 'on'
            app.AddbeamButton.Enable = 'off'
            app.Beam_length.Enable = 'off'
            datacursormode = 'on'
            app.DeflectionSlider.Limits = [0 app.Beam_length.Value];
            app.ShearForceSlider.Limits = [0 app.Beam_length.Value];
            app.BendingMomentSlider.Limits = [0 app.Beam_length.Value];
        end

        % Value changed function: Beam_length
        function Beam_lengthValueChanged(app, event)
           %enabling the Add_Beam_button
            value = app.Beam_length.Value; 
            
            if value ~= 0
                app.AddbeamButton.Enable = 'on';
            end
        end

        % Button pushed function: Support1
        function Support1Pushed(app, event)
           %support type one
            app.Supporttype = 1;
            app.Position_of_Support.Enable = 'on'
            app.Support_left.Enable = 'on'
            app.Support_right.Enable = 'on'
        end

        % Button pushed function: Support2
        function Support2ButtonPushed(app, event)
           %supporttype 2
            app.Supporttype = 2;
            app.Position_of_Support.Enable = 'on'
            app.Support_left.Enable = 'on'
            app.Support_right.Enable = 'on'
        end

        % Button pushed function: Support3
        function Support3ButtonPushed(app, event)
          %supporttype 3
            app.Supporttype = 3;
            app.Position_of_Support.Enable = 'off'
            app.Support_left.Enable = 'on'
            app.Support_right.Enable = 'on'
        end

        % Button pushed function: Support_right
        function Support_rightButtonPushed(app, event)
            %position
            app.Position_of_Support.Value = app.Beam_length.Value;
            app.AddSupport.Enable ='on'
        end

        % Button pushed function: AddSupport
        function AddSupportButtonPushed(app, event)
        %showing supports
            if app.Supporttype == 1
                fig = app.UIFigure;
                ax = uiaxes(fig,'position',[app.Position_of_Support.Value/app.Beam_length.Value*426 + 96, 73, 40, 34]);
                ax.FontSize = 0.001
                ax.XColor = 'none'
                ax.YColor = 'none'
                imshow('Support1.png','parent',ax);
           elseif app.Supporttype == 2
                fig = app.UIFigure;
                ax = uiaxes(fig,'position',[app.Position_of_Support.Value/app.Beam_length.Value*426 + 96, 73, 40, 34]);
                ax.FontSize = 0.001
                ax.XColor = 'none'
                ax.YColor = 'none'
                imshow('Support2.png','parent',ax) 
           elseif app.Supporttype == 3
               if app.Position_of_Support.Value == 0
                fig = app.UIFigure;
                ax = uiaxes(fig,'position',[app.Position_of_Support.Value/app.Beam_length.Value*460 + 92, 78, 20, 75]);
                ax.YAxisLocation = 'left'
                ax.FontSize = 0.001
                ax.XColor = 'none'
                ax.YColor = 'none'
                imshow('Support3_l.png','parent',ax)  
               else
                fig = app.UIFigure;
                ax = uiaxes(fig,'position',[app.Position_of_Support.Value/app.Beam_length.Value*455 + 92, 72, 20, 89.7499]);
                ax.YAxisLocation = 'right'
                ax.FontSize = 0.001
                ax.XColor = 'none'
                ax.YColor = 'none'
                imshow('Support3_r.png','parent',ax)
               end
            end
                   
          
         %storing data of Supports
           app.Supports(app.i, 1) = app.Position_of_Support.Value;
           app.Supports(app.i, 2) = app.Supporttype;
         %enabling loads and moments and supports fields and buttons
           app.Support1.Enable = 'on'
           app.Support2.Enable = 'on'
           app.Support3.Enable = 'on'
           app.Position_of_Support.Enable = 'off'
           app.Support_left.Enable = 'off'
           app.Support_right.Enable = 'off'
           app.AddSupport.Enable = 'off'

           if (app.i == 1 && app.Supports(app.i, 2) == 3) || (app.i >= 2)
                app.PLoad_P.Enable = 'on'
                app.PLoad_V.Enable = 'on'
                app.DLoad_P_S.Enable = 'on'
                app.DLoad_P_E.Enable = 'on'
                app.DLoad_V_S.Enable = 'on'
                app.DLoad_V_E.Enable = 'on'
                app.Moment_P.Enable = 'on'
                app.Moment_V.Enable = 'on'
                app.AddButton.Enable = 'on'
                app.ClearButton.Enable = 'on'
           end
           
           app.i = app.i + 1;
               
        end

        % Value changed function: Position_of_Support
        function Position_of_SupportValueChanged(app, event)
            value = app.Position_of_Support.Value;
            app.AddSupport.Enable ='on'
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.SolveButton.Enable = 'on'
            
          %storing data of loads and moments
            app.Sum_PLoad(app.j, 1) = app.PLoad_V.Value;
            app.Sum_PLoad(app.j, 2) = app.PLoad_P.Value - 0.000001;
            app.Sum_DLoad(app.j, 1) = app.DLoad_V_S.Value;
            app.Sum_DLoad(app.j, 2) = app.DLoad_V_E.Value;
            app.Sum_DLoad(app.j, 3) = app.DLoad_P_S.Value - 0.000001;
            app.Sum_DLoad(app.j, 4) = app.DLoad_P_E.Value - 0.000001;
            app.Sum_Moment(app.j, 1) = app.Moment_V.Value;
            app.Sum_Moment(app.j, 2) = app.Moment_P.Value - 0.000001;
            app.j = app.j + 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            delete(app.UIFigure)
            app = Project1
        end

        % Button pushed function: Support_left
        function Support_leftButtonPushed(app, event)
            app.Position_of_Support.Value = 0;
            app.AddSupport.Enable ='on'
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            syms v_1(x) M_1(x) def_1(x) M_2(x) def_2(x) def_1_2(x) def_3(x) v(x)
            M_1(x) = 0;
            v_1(x) = 0;
            M_2(x) = 0;
            [m1, n1] = size(app.Sum_PLoad);
            [m2, n2] = size(app.Sum_DLoad); 
            [m3, n3] = size(app.Sum_Moment);
            
            for counter = 1:1:m1
                    v_1(x) = v_1(x) - 10^3*app.Sum_PLoad(counter, 1)*heaviside(-10^(-10) + x - app.Sum_PLoad(counter, 2));
                    M_1(x) = M_1(x) + 10^3*app.Sum_PLoad(counter, 1)*heaviside(-10^(-10) + x - app.Sum_PLoad(counter, 2))*app.Sum_PLoad(counter, 2);
            end
            
            for counter = 1:1:m2
                if ~isnan((app.Sum_DLoad(counter, 2) - app.Sum_DLoad(counter, 1))/(app.Sum_DLoad(counter, 4) - app.Sum_DLoad(counter, 3)));
                 v_1(x) = v_1(x) - 10^3*int(((app.Sum_DLoad(counter, 2) - app.Sum_DLoad(counter, 1))/(app.Sum_DLoad(counter, 4) - app.Sum_DLoad(counter, 3))*(x - app.Sum_DLoad(counter, 3)) + app.Sum_DLoad(counter, 1))*(heaviside(-10^(-10) + x - app.Sum_DLoad(counter, 3)) - heaviside(x - app.Sum_DLoad(counter, 4))), app.Sum_DLoad(counter, 3), x);
                 M_1(x) = M_1(x) + 10^3*int(((app.Sum_DLoad(counter, 2) - app.Sum_DLoad(counter, 1))/(app.Sum_DLoad(counter, 4) - app.Sum_DLoad(counter, 3))*(x - app.Sum_DLoad(counter, 3)) + app.Sum_DLoad(counter, 1))*(heaviside(-10^(-10) + x - app.Sum_DLoad(counter, 3)) - heaviside(x - app.Sum_DLoad(counter, 4)))*x, app.Sum_DLoad(counter, 3), app.Sum_DLoad(counter, 4));
                end
            end
            
            for counter = 1:1:m3
                if ~isnan(app.Sum_Moment(counter, 1))
                    M_2(x) = M_2(x) - 10^3*app.Sum_Moment(counter, 1)*heaviside(-10^(-10) + x - app.Sum_Moment(counter, 2));
                    M_1(x) = M_1(x) - 10^3*app.Sum_Moment(counter, 1)*heaviside(-10^(-10) + x - app.Sum_Moment(counter, 2));
                end

            end
            
            [m4, n4] = size(app.Supports);
            app.Supports = sortrows(app.Supports);
            M_2(x) = M_2(x) + int(v_1(x));
            def_1(x) = int(int(M_2(x)));
            def_1_2(x) = int(M_2(x));
                       
            if app.Supports(1, 2) == 3 && app.Supports(m4, 2) == 3 && m4 ~= 1 %two fixed supports
                status = 1;
            elseif (app.Supports(1, 2) ~= 3 && app.Supports(m4, 2) == 3) || (m4 == 1 && app.Supports(1, 2) == 3 && app.Supports(1, 1) == app.Beam_length.Value) %one fixed support at end
                status = 2;
            elseif (app.Supports(1, 2) == 3 && app.Supports(m4, 2) ~= 3) || (m4 == 1 && app.Supports(1, 2) == 3 && app.Supports(1, 1) == 0) %one fixed support at start
                status = 3;
            elseif app.Supports(1, 2) ~= 3 && app.Supports(m4, 2) ~= 3 %no fixed support
                status = 4;
            end
            
            coefficient_matrix = zeros(2*m4 + 2, 2*m4 + 2);
            know_matrix = zeros(2*m4 + 2, 1);
            
            counter1 = 1;
            for counter2 = 1:1:m4
                coefficient_matrix(counter1, counter2) = 1;
            end
            for counter2 = m4 + 1:1:2*m4 + 2
                coefficient_matrix(counter1, counter2) = 0;
            end
                    
            counter1 = 2;
            for counter2 = 1:1:m4
                coefficient_matrix(counter1, counter2) = -app.Supports(counter2, 1);
            end
            for counter2 = m4 + 1:1:2*m4
                coefficient_matrix(counter1, counter2) = 1;
            end
            for counter2 = 2*m4 + 1:2*m4 + 2
                coefficient_matrix(counter1, counter2) = 0;
            end
            for counter1 = 3:1:m4 + 2
                for counter2 = 1:1:m4
                     coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(counter1 - 2, 1) - app.Supports(counter2, 1))*(app.Supports(counter1 - 2, 1) - app.Supports(counter2, 1))^3/6/app.EI.Value/10^6;
                end
                for counter2 = m4 + 1:1:2*m4
                    coefficient_matrix(counter1 ,counter2) = heaviside(-10^(-10) + app.Supports(counter1 - 2, 1) - app.Supports(counter2 - m4, 1))*(app.Supports(counter1 - 2, 1) - app.Supports(counter2 - m4, 1))^2/2/app.EI.Value/10^6;
                end
                counter2 = 2*m4 + 1;
                coefficient_matrix(counter1, counter2) = 1*app.Supports(counter1 - 2, 1)/app.EI.Value/10^6;
                counter2 = 2*m4 +2;
                coefficient_matrix(counter1, counter2) = 1/app.EI.Value/10^6;
            end
            
            if status == 1
               counter1 = m4 + 3;
               for counter2 = 1:1:m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(1, 1) - app.Supports(counter2, 1))*(app.Supports(1, 1) - app.Supports(counter2, 1))^2/2/app.EI.Value/10^6;
               end
               for counter2 = m4 + 1:1:2*m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(1, 1) - app.Supports(counter2 - m4, 1))*(app.Supports(1, 1) - app.Supports(counter2 - m4, 1))/app.EI.Value/10^6;
               end
               counter2 = 2*m4 + 1;
               coefficient_matrix(counter1, counter2) = 1/app.EI.Value/10^6;
               counter2 = 2*m4 +2;
               coefficient_matrix(counter1,counter2) = 0;
               counter1 = m4 + 4;
               for counter2 = 1:1:m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(m4, 1) - app.Supports(counter2, 1))*(app.Supports(m4, 1) - app.Supports(counter2, 1))^2/2/app.EI.Value/10^6;
               end
               for counter2 = m4 + 1:1:2*m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(m4, 1) - app.Supports(counter2 - m4, 1))*(app.Supports(m4, 1) - app.Supports(counter2 - m4, 1))/app.EI.Value/10^6;
               end
               counter2 = 2*m4 + 1;
               coefficient_matrix(counter1, counter2) =  1/app.EI.Value/10^6;
               counter2 = 2*m4 +2;
               coefficient_matrix(counter1,counter2) = 0;
               counter2 = 2;
               for counter1 = m4 + 5:1:2*m4 +2
                       coefficient_matrix(counter1, m4 + counter2) = 1;
                       counter2 = counter2 + 1;
               end
            elseif status == 2
               counter1 = m4 + 3;
               for counter2 = 1:1:m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(m4, 1) - app.Supports(counter2, 1))*(app.Supports(m4, 1) - app.Supports(counter2, 1))^2/2/app.EI.Value/10^6;
               end
               for counter2 = m4 + 1:1:2*m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(m4, 1) - app.Supports(counter2 - m4, 1))*(app.Supports(m4, 1) - app.Supports(counter2 - m4, 1))/app.EI.Value/10^6;
               end
               counter2 = 2*m4 + 1;
               coefficient_matrix(counter1, counter2) = 1/app.EI.Value/10^6;
               counter2 = 2*m4 +2;
               coefficient_matrix(counter1,counter2) = 0;
               counter2 = 1;
               for counter1 = m4 + 4:1:2*m4 + 2
                        coefficient_matrix(counter1, m4 + counter2) = 1;
                        counter2 = counter2 + 1;
                    
               end
            elseif status == 3
               counter1 = m4 + 3;
               for counter2 = 1:1:m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(1, 1) - app.Supports(counter2, 1))*(app.Supports(1, 1) - app.Supports(counter2, 1))^2/2/app.EI.Value/10^6;
               end
               for counter2 = m4 + 1:1:2*m4
                        coefficient_matrix(counter1, counter2) = heaviside(-10^(-10) + app.Supports(1, 1) - app.Supports(counter2 - m4, 1))*(app.Supports(1, 1) - app.Supports(counter2 - m4, 1))/app.EI.Value/10^6;
               end
               counter2 = 2*m4 + 1;
               coefficient_matrix(counter1, counter2) =  1/app.EI.Value/10^6;
               counter2 = 2*m4 +2;
               counter2 = 2;
               coefficient_matrix(counter1,counter2) = 0;
               for counter1 = m4 + 4:1:2*m4 + 2
                     coefficient_matrix(counter1, m4 + counter2) = 1;
                     counter2 = counter2 + 1;
               end
               
            else 
                counter2 = 1;
                for counter1 = m4 + 3:1:2*m4 + 2
                     coefficient_matrix(counter1, m4 + counter2) = 1;
                     counter2 = counter2 + 1;
                end
            end
            
            counter1 = 1;
            right_hand_side_matrix(counter1, 1) = -v_1(app.Beam_length.Value);
            counter1 = 2;
            right_hand_side_matrix(counter1, 1) = -M_1(app.Beam_length.Value);
            for counter1 = 3:1:m4 + 2
                right_hand_side_matrix(counter1, 1) = -def_1(app.Supports(counter1 - 2, 1))/app.EI.Value/10^6;
            end
            if status == 1
                counter1 = m4 + 3;
                right_hand_side_matrix(counter1, 1) = -def_1_2(app.Supports(1, 1))/app.EI.Value/10^6;
                counter1 = m4 + 4;
                right_hand_side_matrix(counter1, 1) = -def_1_2(app.Supports(m4, 1))/app.EI.Value/10^6;
                for counter1 = m4 + 5:1:2*m4 + 2
                    right_hand_side_matrix(counter1, 1) = 0;
                end
            elseif status == 2
                counter1 = m4 + 3;
                right_hand_side_matrix(counter1, 1) = -def_1_2(app.Supports(m4, 1))/app.EI.Value/10^6;
                for counter1 = m4 + 4:1:2*m4 + 2
                    right_hand_side_matrix(counter1, 1) = 0;
                end   
            elseif status == 3
                counter1 = m4 + 3;
                right_hand_side_matrix(counter1, 1) = -def_1_2(app.Supports(1))/app.EI.Value/10^6;
                for counter1 = m4 + 4:1:2*m4 + 2
                    right_hand_side_matrix(counter1, 1) = 0;
                end
            else
                for counter1 = m4 + 3:1:2*m4 + 2
                    right_hand_side_matrix(counter1, 1) = 0;
                end
            end
            
            results = coefficient_matrix\right_hand_side_matrix;
            v(x) = 0;
            
            for counter1 = 1:1:m4
                v(x) = v(x) + results(counter1)*heaviside(-10^(-10) + x - app.Supports(counter1, 1))*(x - app.Supports(counter1, 1))^3/6/app.EI.Value/10^6;
            end 
            
            for counter1 = m4 + 1:1:2*m4
                v(x) = v(x) + results(counter1)*heaviside(-10^(-10) + x - app.Supports(counter1 - m4, 1))*(x - app.Supports(counter1 - m4, 1))^2/2/app.EI.Value/10^6;
            end
            
            counter1 = 2*m4 + 1;
            v(x) = v(x) + results(counter1)*x/app.EI.Value/10^6;
            counter1 = 2*m4 + 2;
            v(x) = v(x) + results(counter1)/app.EI.Value/10^6;
            v(x) = v(x) + def_1(x)/app.EI.Value/10^6;
            M_2(x) = M_2(x) - int(v_1(x));
            
            for counter1 = 1:1:m4
                 v_1(x) = results(counter1)*heaviside(-10^(-10) + x - app.Supports(counter1, 1)) + v_1(x);
            end
            for counter1 = m4 + 1:1:2*m4
                 M_2(x) = results(counter1)*heaviside(-10^(-10) + x - app.Supports(counter1 - m4, 1)) + M_2(x);
            end
            
            M_2(x) = M_2(x) + int(v_1(x));
            x = 0:0.05:app.Beam_length.Value;
            x_c1 = 0.0000000000000001;
            x_c2 = app.Beam_length.Value:0.0000000000000001:app.Beam_length.Value + 0.0000000000000001;
            x = [x_c1 x x_c2];  
            plot(app.UIAxes, x, v(x))
            plot(app.UIAxes_2, x, v_1(x))
            plot(app.UIAxes_3, x, M_2(x))
            app.Max_Deflection.Text = [num2str(double(max(abs(v(x))))) ,' (', app.length_unit, ')'];
            app.Max_SForce.Text = [num2str(double(max(abs(v_1(x))))), ' (', app.force_unit, ')'];
            app.Max_Bmoment.Text = [num2str(double(max(abs(M_2(x))))), ' (', app.force_unit, '.', app.length_unit, ')'];
            app.v = v;
            app.v_1 = v_1;
            app.M_2 = M_2;
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            value = app.UnitsDropDown.Value;
            if value == 'SI'
                app.force_unit = 'N'
                app.length_unit = 'm'
                app.EMpaMpsiLabel.Text= ['E ', '(Mpa):']
                app.Im4in4Label.Text = ['I ', '(m^4):']
                app.BeamlengthminLabel.Text = ['Beam length ', '(m):']
                app.EIMPam4Mpsiin2Label.Text = ['E.I ','(Mpa.m^4):']
                app.PositionmLabel_4.Text = ['Position ','(m):']
                app.PositionminLabel.Text = ['Position ','(m):']
                app.ValuekNkipsLabel.Text = ['Value ', '(kN):']
                app.PositionofstartpointminLabel.Text = ['Position of start point ','(m):']
                app.PositionofendpointminLabel.Text = ['Position of end point ', '(m):']
                app.Start_valuekNkipsLabel.Text = ['Start value', '(kN):']
                app.End_valuekNkipsLabel.Text = ['End value', '(kN):']
                app.PositionminLabel_2.Text = ['Position ','(m):']
                app.ValuekNmkipsinLabel.Text = ['Value ', '(kN.m):']
            else
                app.force_unit = 'lb'
                app.length_unit = 'in'
                app.EMpaMpsiLabel.Text= ['E ', '(Mpsi):']
                app.Im4in4Label.Text = ['I ', '(in^4):']
                app.BeamlengthminLabel.Text = ['Beam length ', '(in):']
                app.EIMPam4Mpsiin2Label.Text = ['E.I ','(Mpsi.in^4):']
                app.PositionmLabel_4.Text = ['Position ','(in):']
                app.PositionminLabel.Text = ['Position ','(in):']
                app.ValuekNkipsLabel.Text = ['Value ', '(lb):']
                app.PositionofstartpointminLabel.Text = ['Position of start point ','(in):']
                app.PositionofendpointminLabel.Text = ['Position of end point ', '(in):']
                app.Start_valuekNkipsLabel.Text = ['Start value', '(kips):']
                app.End_valuekNkipsLabel.Text = ['End value', '(kips):']
                app.PositionminLabel_2.Text = ['Position ','(in):']
                app.ValuekNmkipsinLabel.Text = ['Value ', '(kips.in):']
            end
            xlabel(app.UIAxes, ['x (' , app.length_unit , ')'], 'Fontsize', 13)
            ylabel(app.UIAxes, ['(',app.length_unit,')'], 'Fontsize', 13)
            xlabel(app.UIAxes_2, ['x (' , app.length_unit , ')'], 'Fontsize', 13)
            ylabel(app.UIAxes_2, ['V (', app.force_unit, ')'], 'Fontsize', 14)
            xlabel(app.UIAxes_3, ['x (' , app.length_unit , ')'], 'Fontsize', 13)
            ylabel(app.UIAxes_3, ['M (', app.force_unit,'.', app.length_unit, ')'], 'Fontsize', 14)
            app.MaximumShearForce.Text = ['Maximum Shear Force (', app.force_unit, '):']
            app.MaximumBendingmoment.Text = ['Maximum Bending moment (', app.force_unit, '.', app.length_unit, '):']
            app.Maximumdeflection.Text = ['Maximum Shear Force (', app.force_unit, '):']
            app.Beam_length.Enable = 'on'
            
            app.E.Enable = 'on'
            
        end

        % Value changed function: EI
        function EIValueChanged(app, event)
            value = app.EI.Value;
            if app.Beam_length.Value ~= 0 && app.EI.Value ~= 0
                app.AddbeamButton.Enable = 'on';
            end 
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.PLoad_V.Value = 0;
            app.PLoad_P.Value = 0;
            app.DLoad_V_S.Value = 0;
            app.DLoad_V_E.Value = 0;
            app.DLoad_P_S.Value = 0;
            app.DLoad_P_E.Value = 0;
            app.Moment_P.Value = 0;
            app.Moment_V.Value = 0;
        end

        % Value changed function: E
        function EValueChanged(app, event)
            value = app.E.Value;
            app.I.Enable = 'on'
        end

        % Value changed function: I
        function IValueChanged(app, event)
            value = app.I.Value;
            app.EI.Value = value*app.E.Value;
        end

        % Callback function
        function deflection_value_atValueChanged(app, event)
            
        end

        % Value changing function: DeflectionSlider
        function DeflectionSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Deflection.Text = [num2str(double(app.v(changingValue))), ' (',app.length_unit, ')'];

        end

        % Value changing function: ShearForceSlider
        function ShearForceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SForce.Text = [num2str(double(app.v_1(changingValue))), ' (', app.force_unit, ')'];
        end

        % Value changing function: BendingMomentSlider
        function BendingMomentSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Bmoment.Text = [num2str(double(app.M_2(changingValue))), ' (', app.force_unit, '.', app.length_unit, ')'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BeamlengthminLabel = uilabel(app.UIFigure);
            app.BeamlengthminLabel.Text = 'Beam length (m, in):';
            app.Beam_length = uieditfield(app.UIFigure, 'numeric');
            app.Beam_length.ValueChangedFcn = createCallbackFcn(app, @Beam_lengthValueChanged, true);
            app.AddbeamButton = uibutton(app.UIFigure, 'push');
            app.AddbeamButton.ButtonPushedFcn = createCallbackFcn(app, @AddbeamButtonPushed, true);
            app.AddbeamButton.Text = 'Add beam';
            app.Support1 = uibutton(app.UIFigure, 'push');
            app.Support1.ButtonPushedFcn = createCallbackFcn(app, @Support1Pushed, true);
            app.Support1.Icon = 'Support1.png';
            app.Support1.Text = '';
            app.SupportsLabel = uilabel(app.UIFigure);
            app.SupportsLabel.Text = 'Supports:';
            app.Support2 = uibutton(app.UIFigure, 'push');
            app.Support2.ButtonPushedFcn = createCallbackFcn(app, @Support2ButtonPushed, true);
            app.Support2.Icon = 'Support2.png';
            app.Support2.Text = '';
            app.Support3 = uibutton(app.UIFigure, 'push');
            app.Support3.ButtonPushedFcn = createCallbackFcn(app, @Support3ButtonPushed, true);
            app.Support3.Icon = 'Support3_l.png';
            app.Support3.Text = '';
            app.Support_left = uibutton(app.UIFigure, 'push');
            app.Support_left.ButtonPushedFcn = createCallbackFcn(app, @Support_leftButtonPushed, true);
            app.Support_left.Text = 'Left';
            app.Support_right = uibutton(app.UIFigure, 'push');
            app.Support_right.ButtonPushedFcn = createCallbackFcn(app, @Support_rightButtonPushed, true);
            app.Support_right.Text = 'Right';
            app.PositionmLabel_4 = uilabel(app.UIFigure);
            app.PositionmLabel_4.Text = 'Position (m):';
            app.Position_of_Support = uieditfield(app.UIFigure, 'numeric');
            app.Position_of_Support.ValueChangedFcn = createCallbackFcn(app, @Position_of_SupportValueChanged, true);
            app.AddSupport = uibutton(app.UIFigure, 'push');
            app.AddSupport.ButtonPushedFcn = createCallbackFcn(app, @AddSupportButtonPushed, true);
            app.AddSupport.Text = 'Add Support';
            app.AppliedForcesLabel = uilabel(app.UIFigure);
            app.AppliedForcesLabel.Text = 'Applied Forces:';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.PositionminLabel = uilabel(app.UIFigure);
            app.PositionminLabel.Text = 'Position (m, in)';
            app.ValuekNkipsLabel = uilabel(app.UIFigure);
            app.ValuekNkipsLabel.Text = 'Value (kN, kips)';
            app.PositionofstartpointminLabel = uilabel(app.UIFigure);
            app.PositionofstartpointminLabel.Text = 'Position of start point (m, in)';
            app.Start_valuekNkipsLabel = uilabel(app.UIFigure);
            app.Start_valuekNkipsLabel.Text = 'Start_value (kN, kips)';
            app.End_valuekNkipsLabel = uilabel(app.UIFigure);
            app.End_valuekNkipsLabel.Text = 'End_value (kN, kips)';
            app.PositionminLabel_2 = uilabel(app.UIFigure);
            app.PositionminLabel_2.Text = 'Position (m, in)';
            app.ValuekNmkipsinLabel = uilabel(app.UIFigure);
            app.ValuekNmkipsinLabel.Text = 'Value (kN.m, kips.in)';
            app.PLoad_P = uieditfield(app.UIFigure, 'numeric');
            app.PLoad_V = uieditfield(app.UIFigure, 'numeric');
            app.DLoad_P_S = uieditfield(app.UIFigure, 'numeric');
            app.DLoad_V_S = uieditfield(app.UIFigure, 'numeric');
            app.DLoad_V_E = uieditfield(app.UIFigure, 'numeric');
            app.Moment_P = uieditfield(app.UIFigure, 'numeric');
            app.Moment_V = uieditfield(app.UIFigure, 'numeric');
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.PointloadLabel = uilabel(app.UIFigure);
            app.PointloadLabel.Text = 'Point load:';
            app.DistributedloadLabel = uilabel(app.UIFigure);
            app.DistributedloadLabel.Text = 'Distributed load:';
            app.MomentLabel = uilabel(app.UIFigure);
            app.MomentLabel.Text = 'Moment:';
            app.DLoad_P_E = uieditfield(app.UIFigure, 'numeric');
            app.PositionofendpointminLabel = uilabel(app.UIFigure);
            app.PositionofendpointminLabel.Text = 'Position of end point (m, in)';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EIMPam4Mpsiin2Label = uilabel(app.UIFigure);
            app.EIMPam4Mpsiin2Label.Text = 'EI (MPa.m^4, Mpsi.in^2):';
            app.EI = uieditfield(app.UIFigure, 'numeric');
            app.EI.ValueChangedFcn = createCallbackFcn(app, @EIValueChanged, true);
            app.UnitsLabel = uilabel(app.UIFigure);
            app.UnitsLabel.Text = 'Units:';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {' ', 'SI', 'US'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = ' ';
            app.DownwardispositiveLabel = uilabel(app.UIFigure);
            app.DownwardispositiveLabel.Text = 'Down ward is positive';
            app.counterclockwiseispositiveLabel = uilabel(app.UIFigure);
            app.counterclockwiseispositiveLabel.Text = 'counter clock-wise is positive';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.I = uieditfield(app.UIFigure, 'numeric');
            app.I.ValueChangedFcn = createCallbackFcn(app, @IValueChanged, true);
            app.Im4in4Label = uilabel(app.UIFigure);
            app.Im4in4Label.Text = 'I (m^4, in^4)';
            app.E = uieditfield(app.UIFigure, 'numeric');
            app.E.ValueChangedFcn = createCallbackFcn(app, @EValueChanged, true);
            app.EMpaMpsiLabel = uilabel(app.UIFigure);
            app.EMpaMpsiLabel.Text = 'E (Mpa, Mpsi)';
            app.Maximumdeflection = uilabel(app.UIFigure);
            app.Maximumdeflection.Text = 'Maximum deflection:';
            app.MaximumShearForce = uilabel(app.UIFigure);
            app.MaximumShearForce.Text = 'Maximum Shear Force';
            app.MaximumBendingmoment = uilabel(app.UIFigure);
            app.MaximumBendingmoment.Text = 'Maximum Bending moment';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Max_Deflection = uilabel(app.UIFigure);
            app.Max_Deflection.Text = ' ';
            app.Max_SForce = uilabel(app.UIFigure);
            app.Max_SForce.Text = ' ';
            app.Max_Bmoment = uilabel(app.UIFigure);
            app.Max_Bmoment.Text = ' ';
            app.DeflectionSliderLabel = uilabel(app.UIFigure);
            app.DeflectionSliderLabel.Text = 'Deflection:';
            app.DeflectionSlider = uislider(app.UIFigure);
            app.DeflectionSlider.ValueChangingFcn = createCallbackFcn(app, @DeflectionSliderValueChanging, true);
            app.ShearForceSliderLabel = uilabel(app.UIFigure);
            app.ShearForceSliderLabel.Text = 'Shear Force:';
            app.ShearForceSlider = uislider(app.UIFigure);
            app.ShearForceSlider.ValueChangingFcn = createCallbackFcn(app, @ShearForceSliderValueChanging, true);
            app.BendingMomentSliderLabel = uilabel(app.UIFigure);
            app.BendingMomentSliderLabel.Text = 'Bending Moment:';
            app.BendingMomentSlider = uislider(app.UIFigure);
            app.BendingMomentSlider.ValueChangingFcn = createCallbackFcn(app, @BendingMomentSliderValueChanging, true);
            app.SForce = uilabel(app.UIFigure);
            app.SForce.Text = '0';
            app.Deflection = uilabel(app.UIFigure);
            app.Deflection.Text = '0';
            app.Bmoment = uilabel(app.UIFigure);
            app.Bmoment.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end